classdef demo_intro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ReDySim                 matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Plot1Tab                matlab.ui.container.Tab
        UIAxesAngle             matlab.ui.control.UIAxes
        Plot2Tab                matlab.ui.container.Tab
        UIAxesTorque            matlab.ui.control.UIAxes
        AnimationTab            matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
        RobotLinkDropDownLabel  matlab.ui.control.Label
        RobotLinkDropDown       matlab.ui.control.DropDown
        UITable                 matlab.ui.control.Table
        DoFEditFieldLabel       matlab.ui.control.Label
        DoFEditField            matlab.ui.control.NumericEditField
        nEditFieldLabel         matlab.ui.control.Label
        nEditField              matlab.ui.control.NumericEditField
        TypeEditFieldLabel      matlab.ui.control.Label
        TypeEditField           matlab.ui.control.NumericEditField
        gxEditFieldLabel        matlab.ui.control.Label
        gxEditField             matlab.ui.control.NumericEditField
        gyEditFieldLabel        matlab.ui.control.Label
        gyEditField             matlab.ui.control.NumericEditField
        gzEditFieldLabel        matlab.ui.control.Label
        gzEditField             matlab.ui.control.NumericEditField
        TpEditFieldLabel        matlab.ui.control.Label
        TpEditField             matlab.ui.control.NumericEditField
        StepSizeEditFieldLabel  matlab.ui.control.Label
        StepSizeEditField       matlab.ui.control.NumericEditField
        startanimationButton    matlab.ui.control.StateButton
        stopanimationButton     matlab.ui.control.StateButton
        AnalyzeButton           matlab.ui.control.Button
        Switch                  matlab.ui.control.Switch
        DefaultValuesButton     matlab.ui.control.Button
        LoadButton              matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        PlotButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        %th1T, th10, th2T, th20, m1, m2, a1, a2 % Description
         th1T = pi; 
         th10 = 0; 
         th2T = pi/2; 
         th20 = 0;
         m1 = 1; 
         m2 = 1; 
         a1 = 1; 
         a2 = 1; 
         g = 9.81;
         value_dropdown = '1-link';
         value_switch = 'ForDyn';
         tspan = [0 10];
         y0 = [0; 0; 0; 0];
         n = 1;
%          d = [1, 1, 0, 0, -9.81, 0, 10, 0];
%          t = [0, 1, 1, 0];
          t, t1, y;
          d;
         inp;
         flag1 = 1;
         flag2 = 1;
%          value_dropdown = '1-link';
%          value_switch = 'ForDyn';
         valButton;
         path;
         ti, th, thd, thdd, tau;
         th1, th2, tor1, tor2;
    end
    
    methods (Access = private)
        
    
        function [] = plot_val(app, value_dropdown, value_switch, flag1, flag2)
            if value_dropdown == "1-link" && value_switch == "InvDyn"
                plot(app.UIAxesAngle, app.ti, app.th, '-', app.ti, app.thd, ':', app.ti, app.thdd, '-.');
                plot(app.UIAxesTorque, app.ti, app.tau)
                xlabel(app.UIAxesAngle, 'Time (sec)')
                ylabel(app.UIAxesAngle, 'Joint motion')
                xlabel(app.UIAxesTorque, 'Time (sec)')
                ylabel(app.UIAxesTorque, 'Joint Torque')
                legend(app.UIAxesTorque, 'Joint Torque');
                
                
            elseif value_dropdown == "2-link arm" && value_switch == "InvDyn" 
                plot (app.UIAxesAngle, app.ti, app.th1,'-', app.ti, app.th2,':')
                plot (app.UIAxesTorque, app.ti, app.tor1,'-', app.ti, app.tor2,':');
                xlabel(app.UIAxesAngle, 'Time (sec)')
                ylabel(app.UIAxesAngle, 'Joint angle')
                xlabel(app.UIAxesTorque, 'Time (sec)')
                ylabel(app.UIAxesTorque, 'Joint Torque')
                
            elseif value_dropdown == "2-link arm" && value_switch == "ForDyn"
                
                plot (app.UIAxesAngle, app.t1, app.y(:,1),'-', app.t1, app.y(:,3),':');
                legend(app.UIAxesAngle, 'th1', 'th1d');
                plot (app.UIAxesTorque, app.t1, app.y(:,2),'-', app.t1, app.y(:,4),':');
                legend(app.UIAxesTorque, 'th2', 'th2d');
                xlabel(app.UIAxesAngle, 'Time (sec)')
                ylabel(app.UIAxesAngle, 'Joint angle (rad) and rate (rad/sec)')
               
                xlabel(app.UIAxesTorque, 'Time (sec)')
                ylabel(app.UIAxesTorque, 'Joint angle (rad) and rate (rad/sec)')
            elseif value_dropdown == "1-link" && value_switch == "ForDyn"   
              

                plot (app.UIAxesAngle, app.t, app.y);
                legend(app.UIAxesAngle, 'th', 'thd');
                           
                
            end
            
        end
    
        function [] = analyze(app, value_dropdown, value_switch, flag1, flag2)
            if value_dropdown == "1-link" && value_switch == "InvDyn"
                
                [app.ti, app.th, app.thd, app.thdd, app.tau] = link1(app.th1T, app.th10, app.m1, app.a1);


            elseif value_dropdown == "2-link arm" && value_switch == "InvDyn" 
                
                [app.ti, app.th1, app.th2, app.tor1, app.tor2] = link2(app.th1T, app.th10, app.th2T, app.th20, app.m1, app.m2, app.a1, app.a2);
                plot (app.UIAxesAngle, app.ti, app.th1,'-', app.ti, app.th2,':')
                plot (app.UIAxesTorque, app.ti, app.tor1,'-', app.ti, app.tor2,':');
                xlabel(app.UIAxesAngle, 'Time (sec)')
                ylabel(app.UIAxesAngle, 'Joint angle')
                xlabel(app.UIAxesTorque, 'Time (sec)')
                ylabel(app.UIAxesTorque, 'Joint Torque')
                
            elseif value_dropdown == "2-link arm" && value_switch == "ForDyn"
                app.path = 'inputs.mat';
                [app.t1, app.y] = ode45('link2mani', app.tspan, app.y0);
                fomode='w';
                fip1=fopen('timevar.dat',fomode);%time
                fip2=fopen('statevar.dat',fomode);%all state variables
                for j=1:length(app.t1)
                    tsim = app.t1(j);
                    %WRITING SOLUTION FOR EACH INSTANT IN FILES
                    fprintf(fip1,'%e\n',tsim);
                    fprintf(fip2,'%e ', app.y(j,:));
                    fprintf(fip2,'\n');
                end

            elseif value_dropdown == "1-link" && value_switch == "ForDyn"   
                
                y0 = [pi/2; 0];
                
                [app.t, app.y] = ode45('link1_for', app.tspan, y0); 
                
            elseif value == "3-link"
                app.path = "inputs_3_link.mat";
                [app.d, app.t, app.inp] = load_input(app, app.path);
                app.flag = 0;
%                 app.stopanimationButton.Value = 1;
%                 app.startanimationButton.Value = app.flag;
%                 animate(app, app.flag, app.inp);
                updateValuesOnScreen(app, app.d, app.t);
                
                
            end
            
        end
    
        
        function [] = updateValuesOnScreen(app, d, t)
            app.DoFEditField.Value = d(1);
            app.nEditField.Value = d(2);
            app.TypeEditField.Value = d(3);
            app.gxEditField.Value = d(4);
            app.gyEditField.Value = d(5);
            app.gzEditField.Value = d(6);
            app.TpEditField.Value = d(7);
            app.StepSizeEditField.Value = d(8);
            app.UITable.Data = t;
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.n = 1;
            req_var = load('variables_1.mat');
            app.m1 = req_var.sv(1);
            app.a1 = req_var.sv(2);
            app.d = [1, 1, 0, 0, -9.81, 0, app.tspan, 0];
            app.t = [0, app.a1, app.m1, 0];
            updateValuesOnScreen(app, app.d, app.t);
            analyze(app, app.value_dropdown, app.value_switch, app.flag1, app.flag2)
            plot_val(app, app.value_dropdown, app.value_switch, app.flag1, app.flag2)
            
        end

        % Value changed function: RobotLinkDropDown
        function RobotLinkDropDownValueChanged(app, event)
            
            app.value_dropdown = app.RobotLinkDropDown.Value;
            
            if app.value_dropdown == "1-link" && app.value_switch == "InvDyn"
                app.n = 1;
                req_var = load('variables_1.mat');
                app.m1 = req_var.sv(1);
                app.a1 = req_var.sv(2);
                app.d = [1, 1, 0, 0, -9.81, 0, 10, 0];
                app.t = [0, app.a1, app.m1, 0];
                app.flag1 = 0;
                updateValuesOnScreen(app, app.d, app.t);
            elseif app.value_dropdown == "2-link arm" && app.value_switch == "InvDyn" 
                req_var = load('variables_2.mat');
                app.m1 = req_var.sv(1);
                app.m2 = req_var.sv(2);
                app.a1 = req_var.sv(3);
                app.a2 = req_var.sv(4);
                app.n = 2;
                app.d = [2, 2, 0, 0, -9.81, 0, 10, 0];
                app.t = [0, app.a1, app.m1, 0; 0, app.a2, app.m2, 0];
                updateValuesOnScreen(app, app.d, app.t);
                
                
            elseif app.value_dropdown == "2-link arm" && app.value_switch == "ForDyn"
                req_var = load('variables_2.mat');

                app.m1 = req_var.sv(1);
                app.m2 = req_var.sv(2);
                app.a1 = req_var.sv(3);
                app.a2 = req_var.sv(4);                
                app.n = 2;
                app.d = [2, 2, 0, 0, -9.81, 0, 10, 0];
                app.t = [0, app.a1, app.m1, 0; 0, app.a2, app.m2, 0];

                updateValuesOnScreen(app, app.d, app.t);
                
            elseif app.value_dropdown == "1-link" && app.value_switch == "ForDyn"   

                app.n = 1;
                req_var = load('variables_1.mat');
                app.m1 = req_var.sv(1);
                app.a1 = req_var.sv(2);
                app.d = [1, 1, 0, 0, -9.81, 0, app.tspan, 0];
                app.t = [0, app.a1, app.m1, 0];
                updateValuesOnScreen(app, app.d, app.t);
            end
        end

        % Callback function
        function AnimateButtonPushed(app, event)
            app.flag = 1;
            animate(app, app.flag, app.inp);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
%             app.stopanimationButton.Value = 1;
            indices = event.Indices;
            newData = event.NewData;
            app.t = app.UITable.Data;
%             app.inp = to_struct(app.t, app.d);
%             app.flag = 0;
            if app.n == 1
                app.m1 = app.UITable.Data(3);
                app.a1 = app.UITable.Data(2);
                sv = [app.m1, app.a1];
                save('variables_1.mat', 'sv' );
                plot_val(app, app.value_dropdown, app.value_switch, app.flag1, app.flag2)
            elseif app.n == 2
                app.m1 = app.UITable.Data(1,3);
                app.a1 = app.UITable.Data(1,2);
                app.m2 = app.UITable.Data(2,3);
                app.a2 = app.UITable.Data(2,2);
                sv = [app.m1, app.m2, app.a1, app.a2];
                save('variables_2.mat', 'sv' );
                plot_val(app, app.value_dropdown, app.value_switch, app.flag1, app.flag2)
            end
%             app.startanimationButton.Value = 0;
%             app.stopanimationButton.Value = 0;
% %             animate(app, app.flag, app.inp);
%             app.stopanimationButton.Value = 1;
%             app.t(indices(1),indices(2)) = newData;
%             disp(app.t)
%             app.UITable.Data = app.t;
%             disp(app.UITable.Data(indices(1),indices(2)));
            
%             disp(indices);
%             disp(newData);
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
        end

        % Value changed function: DoFEditField
        function DoFEditFieldValueChanged(app, event)
            app.DoF = app.DoFEditField.Value;
            
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            value = app.nEditField.Value;
            
        end

        % Value changed function: TypeEditField
        function TypeEditFieldValueChanged(app, event)
            value = app.TypeEditField.Value;
            
        end

        % Value changed function: gxEditField
        function gxEditFieldValueChanged(app, event)
            value = app.gxEditField.Value;
            
        end

        % Value changed function: gyEditField
        function gyEditFieldValueChanged(app, event)
            value = app.gyEditField.Value;
            
        end

        % Value changed function: gzEditField
        function gzEditFieldValueChanged(app, event)
            value = app.gzEditField.Value;
            
        end

        % Value changed function: TpEditField
        function TpEditFieldValueChanged(app, event)
            value = app.TpEditField.Value;
            
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            value = app.StepSizeEditField.Value;
            
        end

        % Value changed function: startanimationButton
        function startanimationButtonValueChanged(app, event)
            app.valButton = app.startanimationButton.Value;
            app.stopanimationButton.Value = 0;
%             app.flag = app.valButton;
            animate_demo(app, 1, app.m1, app.m2, app.a1, app.a2);
            disp(app.valButton);
            
        end

        % Value changed function: stopanimationButton
        function stopanimationButtonValueChanged(app, event)
            value = app.stopanimationButton.Value;
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            analyze(app, app.value_dropdown, app.value_switch, app.flag1, app.flag2)
%             runfor(app, app.path);
        end

        % Callback function
        function initialiseButtonPushed(app, event)
            runfor(app, 'inputs.mat');
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.value_switch = app.Switch.Value;
            plot_val(app, app.value_dropdown, app.value_switch, app.flag1, app.flag2);
            disp(app.value_switch)
            
        end

        % Button pushed function: DefaultValuesButton
        function DefaultValuesButtonPushed(app, event)
            if app.n == 1
                sv = [1, 1];
                save('variables_1.mat', 'sv' );
            elseif app.n == 2
                sv = [1, 1, 1, 1];
                save('variables_2.mat', 'sv' );
            end
            plot_val(app, app.value_dropdown, app.value_switch, app.flag1, app.flag2);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.n == 1
                sv = [app.m1, app.a1];
                save('variables_1.mat', 'sv' );
            elseif app.n == 2
                sv = [app.m1, app.m2, app.a1, app.a2];
                save('variables_2.mat', 'sv' );
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.mat'}, 'Select a .mat input data file');
            loaddata = fullfile(pathname,filename);
%             source_data = load(loaddata)
            if app.n == 1
                copyfile loaddata variables_1.mat 
            elseif app.n == 2
                copyfile loaddata variables_2.mat 
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plot_val(app, app.value_dropdown, app.value_switch, app.flag1, app.flag2)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ReDySim = uifigure;
            app.ReDySim.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.ReDySim);
            app.Plot1Tab = uitab(app.TabGroup);
            app.Plot1Tab.Title = 'Plot 1';
            app.UIAxesAngle = uiaxes(app.Plot1Tab);
            title(app.UIAxesAngle, '')
            xlabel(app.UIAxesAngle, 'X')
            ylabel(app.UIAxesAngle, 'Y')
            app.UIAxesAngle.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Plot2Tab = uitab(app.TabGroup);
            app.Plot2Tab.Title = 'Plot 2';
            app.UIAxesTorque = uiaxes(app.Plot2Tab);
            title(app.UIAxesTorque, '')
            xlabel(app.UIAxesTorque, 'X')
            ylabel(app.UIAxesTorque, 'Y')
            app.AnimationTab = uitab(app.TabGroup);
            app.AnimationTab.Title = 'Animation';
            app.UIAxes = uiaxes(app.AnimationTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RobotLinkDropDownLabel = uilabel(app.ReDySim);
            app.RobotLinkDropDownLabel.Text = 'Robot Link';
            app.RobotLinkDropDown = uidropdown(app.ReDySim);
            app.RobotLinkDropDown.Items = {'Select', '1-link', '2-link arm', '2-link manipulator', '3-link', ''};
            app.RobotLinkDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotLinkDropDownValueChanged, true);
            app.RobotLinkDropDown.Value = '1-link';
            app.UITable = uitable(app.ReDySim);
            app.UITable.ColumnName = {'alp'; 'a'; 'm'; 'b'; 'th'; 'bt'; 'r'; 'dx'; 'dy'; 'dz'; 'm'; 'Icxx'; 'Icyy'; 'Iczz'; 'Icxy'; 'Icyz'; 'Iczx'; 'aj'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DoFEditFieldLabel = uilabel(app.ReDySim);
            app.DoFEditFieldLabel.Text = 'DoF';
            app.DoFEditField = uieditfield(app.ReDySim, 'numeric');
            app.DoFEditField.ValueChangedFcn = createCallbackFcn(app, @DoFEditFieldValueChanged, true);
            app.nEditFieldLabel = uilabel(app.ReDySim);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.ReDySim, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.TypeEditFieldLabel = uilabel(app.ReDySim);
            app.TypeEditFieldLabel.Text = 'Type';
            app.TypeEditField = uieditfield(app.ReDySim, 'numeric');
            app.TypeEditField.ValueChangedFcn = createCallbackFcn(app, @TypeEditFieldValueChanged, true);
            app.gxEditFieldLabel = uilabel(app.ReDySim);
            app.gxEditFieldLabel.Text = 'gx';
            app.gxEditField = uieditfield(app.ReDySim, 'numeric');
            app.gxEditField.ValueChangedFcn = createCallbackFcn(app, @gxEditFieldValueChanged, true);
            app.gyEditFieldLabel = uilabel(app.ReDySim);
            app.gyEditFieldLabel.Text = 'gy';
            app.gyEditField = uieditfield(app.ReDySim, 'numeric');
            app.gyEditField.ValueChangedFcn = createCallbackFcn(app, @gyEditFieldValueChanged, true);
            app.gzEditFieldLabel = uilabel(app.ReDySim);
            app.gzEditFieldLabel.Text = 'gz';
            app.gzEditField = uieditfield(app.ReDySim, 'numeric');
            app.gzEditField.ValueChangedFcn = createCallbackFcn(app, @gzEditFieldValueChanged, true);
            app.TpEditFieldLabel = uilabel(app.ReDySim);
            app.TpEditFieldLabel.Text = 'Tp';
            app.TpEditField = uieditfield(app.ReDySim, 'numeric');
            app.TpEditField.ValueChangedFcn = createCallbackFcn(app, @TpEditFieldValueChanged, true);
            app.StepSizeEditFieldLabel = uilabel(app.ReDySim);
            app.StepSizeEditFieldLabel.Text = 'StepSize';
            app.StepSizeEditField = uieditfield(app.ReDySim, 'numeric');
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.startanimationButton = uibutton(app.ReDySim, 'state');
            app.startanimationButton.ValueChangedFcn = createCallbackFcn(app, @startanimationButtonValueChanged, true);
            app.startanimationButton.Text = 'start animation';
            app.stopanimationButton = uibutton(app.ReDySim, 'state');
            app.stopanimationButton.ValueChangedFcn = createCallbackFcn(app, @stopanimationButtonValueChanged, true);
            app.stopanimationButton.Text = 'stop animation';
            app.AnalyzeButton = uibutton(app.ReDySim, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.Switch = uiswitch(app.ReDySim, 'slider');
            app.Switch.Items = {'ForDyn', 'InvDyn'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'ForDyn';
            app.DefaultValuesButton = uibutton(app.ReDySim, 'push');
            app.DefaultValuesButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultValuesButtonPushed, true);
            app.DefaultValuesButton.Text = 'Default Values ';
            app.LoadButton = uibutton(app.ReDySim, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ReDySim, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.PlotButton = uibutton(app.ReDySim, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = demo_intro

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ReDySim)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ReDySim)
        end
    end
end